classdef setup_gest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupUIFigure              matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        VisualizerModeButtonGroup  matlab.ui.container.ButtonGroup
        RealTimeButton             matlab.ui.control.ToggleButton
        PlayBackButton             matlab.ui.control.ToggleButton
        COMPanel                   matlab.ui.container.Panel
        CFG_PORTLabel              matlab.ui.control.Label
        CfgPort                    matlab.ui.control.NumericEditField
        DATA_PORTLabel             matlab.ui.control.Label
        DatPort                    matlab.ui.control.NumericEditField
        TestConnectionButton       matlab.ui.control.Button
        DatPanel                   matlab.ui.container.Panel
        DatButton                  matlab.ui.control.Button
        DataFileLabel              matlab.ui.control.Label
        DatPath                    matlab.ui.control.EditField
        SelectCFGFilePanel         matlab.ui.container.Panel
        CFGFileLabel               matlab.ui.control.Label
        CfgPath                    matlab.ui.control.EditField
        CfgButton                  matlab.ui.control.Button
        SetupCompleteButton        matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        mode = 1;
        datFile = struct('name', [], 'path', []);
        cfgFile = struct('name', [], 'path', []);
        logFile = struct('name', [], 'path', []);
        offset = struct('height', [], 'az', [], 'rot', []);
        comPort = struct('cfg',1,'data',1,'status',0);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SetupUIFigure.Visible = 'off';
            movegui(app.SetupUIFigure,'center');
            try
                if exist('state.mat')
                    load('state.mat')
                    app.RealTimeButton.Value = REAL_TIME_MODE;
                    app.CfgPath.Value = [cfgFile.path cfgFile.name];
                    

                    app.CfgPort.Value = comPort.cfg;
                    app.DatPort.Value = comPort.data;

                end
            catch ME
                
            end
            uiwait(app.SetupUIFigure)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SetupUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {349, 349};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: VisualizerModeButtonGroup
        function VisualizerModeButtonGroupSelectionChanged(app, event)
            if(app.RealTimeButton.Value)
                app.mode = 1;
                app.DatPanel.Visible = 'off';
                app.COMPanel.Visible = 'on';
                app.RecordDataPanel.Visible = 'on';
            else
                app.mode = 2;
                app.DatPanel.Visible = 'on';
                app.COMPanel.Visible = 'off';
                app.RecordDataPanel.Visible = 'off';
                
            end
            
        end

        % Button pushed function: DatButton
        function DatButtonPushed(app, event)
            [app.datFile.name app.datFile.path] = uigetfile('*.txt','Select Log File');
            if(ischar(app.datFile.name))
                app.DatPath.Value = [app.datFile.path app.datFile.name] ;
            end
            figure(app.SetupUIFigure);
        end

        % Button pushed function: CfgButton
        function CfgButtonPushed(app, event)
            [app.cfgFile.name app.cfgFile.path] = uigetfile('*.cfg','Select CFG File');
            if(ischar(app.cfgFile.name))
                app.CfgPath.Value =  [app.cfgFile.path app.cfgFile.name] ;
            end
            figure(app.SetupUIFigure);
        end

        % Callback function
        function LogButtonPushed(app, event)
            app.logFile.path = uigetdir('Select Folder to Save');
            if(ischar(app.logFile.path))
                app.SavePath.Value = [app.logFile.path];
            end
            figure(app.SetupUIFigure);
        end

        % Value changed function: CfgPath
        function CfgPathValueChanged(app, event)
            [app.cfgFile.path,name,ext] = fileparts(app.CfgPath.Value);
            app.cfgFile.name = [name ext];
        end

        % Value changed function: DatPath
        function DatPathValueChanged(app, event)
            [app.datFile.path,name,ext] = fileparts(app.DatPath.Value);
            app.datFile.name = [name ext];
        end

        % Value changed function: CfgPort
        function CfgPortValueChanged(app, event)
            app.comPort.cfg = app.CfgPort.Value;
        end

        % Value changed function: DatPort
        function DatPortValueChanged(app, event)
            app.comPort.data = app.DatPort.Value;
        end

        % Button pushed function: TestConnectionButton
        function TestConnectionButtonPushed(app, event)
            hDataPort = initDataPort(app.DatPort.Value);
            hCfgPort = initCfgPort(app.CfgPort.Value);
            
            % send version command
            if(hCfgPort ~= -1 && hDataPort ~=-1)
                if(hDataPort.BytesAvailable)
                    %TODO: remove warning when config reload w/o NRST is enabled
                    uialert(app.SetupUIFigure, 'Device appears to already be running. Will not be able to load a new configuration. To load a new config, press NRST on the EVM and try again.','Need to assert NRST');    
                    app.comPort.status = -1;
                    return;
                else       
                    fprintf(hCfgPort, 'version');
                    pause(0.5); % adding some delay to make sure bytes are received
                    response = '';
                    if(hCfgPort.BytesAvailable)
                        for i=1:10 % version command reports back 10 lines TODO: change if SDK changes response
                            rstr = fgets(hCfgPort);
                            response = append(response, rstr);
                        end
                        uialert(app.SetupUIFigure, response,'Test successful: CFG Port Opened & Data Received','icon','success');
                        app.comPort.status = 1;
                    else
                        uialert(app.SetupUIFigure,'Port opened but no response received. Check port # and SOP mode on EVM','Issue with CFG Port');
                        app.comPort.status = -2;
                        fclose(hDataPort);
                        fclose(hCfgPort);
                    end
                end
            else
                app.comPort.status = -2;
                uialert(app.SetupUIFigure, 'Could not open ports. Check port # and that EVM is powered with correct SOP mode.','Ports not valid');    
            end
        end

        % Button pushed function: SetupCompleteButton
        function SetupCompleteButtonPushed(app, event)

           REAL_TIME_MODE = app.RealTimeButton.Value;
           [cPath,cName,cExt] = fileparts(app.CfgPath.Value);
           cfgFile.path = [cPath '\'];
           cfgFile.name = [cName cExt];
           comPort.cfg = app.CfgPort.Value;
           comPort.data = app.DatPort.Value;
           comPort.status = app.comPort.status;
  
            assignin('base', 'REAL_TIME_MODE', REAL_TIME_MODE);
            assignin('base', 'cfgFile', cfgFile);
            assignin('base', 'comPort', comPort);
            save('state.mat','REAL_TIME_MODE','cfgFile','comPort');
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetupUIFigure = uifigure('Visible', 'off');
            app.SetupUIFigure.Name = 'Setup ';
            app.SetupUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SetupUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VisualizerModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.VisualizerModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizerModeButtonGroupSelectionChanged, true);
            app.VisualizerModeButtonGroup.Title = 'Visualizer Mode';
            app.RealTimeButton = uitogglebutton(app.VisualizerModeButtonGroup);
            app.RealTimeButton.Text = 'Real Time';
            app.RealTimeButton.Value = true;
            app.PlayBackButton = uitogglebutton(app.VisualizerModeButtonGroup);
            app.PlayBackButton.Enable = 'off';
            app.PlayBackButton.Visible = 'off';
            app.PlayBackButton.Text = 'Play Back';
            app.COMPanel = uipanel(app.VisualizerModeButtonGroup);
            app.COMPanel.Title = 'Set COM port for real time';
            app.CFG_PORTLabel = uilabel(app.COMPanel);
            app.CFG_PORTLabel.Text = 'CFG_PORT';
            app.CfgPort = uieditfield(app.COMPanel, 'numeric');
            app.CfgPort.Limits = [1 99];
            app.CfgPort.ValueChangedFcn = createCallbackFcn(app, @CfgPortValueChanged, true);
            app.CfgPort.Tooltip = {'Appears as User UART or Standard Port in Device Manager'};
            app.CfgPort.Value = 1;
            app.DATA_PORTLabel = uilabel(app.COMPanel);
            app.DATA_PORTLabel.Text = 'DATA_PORT';
            app.DatPort = uieditfield(app.COMPanel, 'numeric');
            app.DatPort.Limits = [1 99];
            app.DatPort.ValueChangedFcn = createCallbackFcn(app, @DatPortValueChanged, true);
            app.DatPort.Tooltip = {'Appears as Data or Enhanced Port in Device Manager'};
            app.DatPort.Value = 1;
            app.TestConnectionButton = uibutton(app.COMPanel, 'push');
            app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
            app.TestConnectionButton.Text = 'Test Connection';
            app.DatPanel = uipanel(app.VisualizerModeButtonGroup);
            app.DatPanel.Title = 'Select data log file for play back';
            app.DatPanel.Visible = 'off';
            app.DatButton = uibutton(app.DatPanel, 'push');
            app.DatButton.ButtonPushedFcn = createCallbackFcn(app, @DatButtonPushed, true);
            app.DatButton.Icon = 'folder_file_icon.png';
            app.DatButton.Text = 'Browse';
            app.DataFileLabel = uilabel(app.DatPanel);
            app.DataFileLabel.Text = 'Data File';
            app.DatPath = uieditfield(app.DatPanel, 'text');
            app.DatPath.ValueChangedFcn = createCallbackFcn(app, @DatPathValueChanged, true);
            app.DatPath.Tooltip = {'Enter full path to file or use Browse button.'};
            app.SelectCFGFilePanel = uipanel(app.LeftPanel);
            app.SelectCFGFilePanel.Title = 'Select CFG File';
            app.CFGFileLabel = uilabel(app.SelectCFGFilePanel);
            app.CFGFileLabel.Text = 'CFG File';
            app.CfgPath = uieditfield(app.SelectCFGFilePanel, 'text');
            app.CfgPath.ValueChangedFcn = createCallbackFcn(app, @CfgPathValueChanged, true);
            app.CfgPath.Tooltip = {'Enter full path to file or use Browse button.'};
            app.CfgButton = uibutton(app.SelectCFGFilePanel, 'push');
            app.CfgButton.ButtonPushedFcn = createCallbackFcn(app, @CfgButtonPushed, true);
            app.CfgButton.Icon = 'folder_file_icon.png';
            app.CfgButton.Text = 'Browse';
            app.SetupCompleteButton = uibutton(app.LeftPanel, 'push');
            app.SetupCompleteButton.ButtonPushedFcn = createCallbackFcn(app, @SetupCompleteButtonPushed, true);
            app.SetupCompleteButton.Text = 'Setup Complete';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.SetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setup_gest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupUIFigure)
        end
    end
end