classdef setup_tm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupUIFigure                 matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        VisualizerModeButtonGroup     matlab.ui.container.ButtonGroup
        RealTimeButton                matlab.ui.control.ToggleButton
        PlayBackButton                matlab.ui.control.ToggleButton
        COMPanel                      matlab.ui.container.Panel
        CFG_PORTLabel                 matlab.ui.control.Label
        CfgPort                       matlab.ui.control.NumericEditField
        DATA_PORTLabel                matlab.ui.control.Label
        DatPort                       matlab.ui.control.NumericEditField
        TestConnectionButton          matlab.ui.control.Button
        DatPanel                      matlab.ui.container.Panel
        DatButton                     matlab.ui.control.Button
        DataFileLabel                 matlab.ui.control.Label
        DatPath                       matlab.ui.control.EditField
        SelectCFGFilePanel            matlab.ui.container.Panel
        CFGFileLabel                  matlab.ui.control.Label
        CfgPath                       matlab.ui.control.EditField
        CfgButton                     matlab.ui.control.Button
        RecordDataPanel               matlab.ui.container.Panel
        SaveDirectoryLabel            matlab.ui.control.Label
        SavePath                      matlab.ui.control.EditField
        LogButton                     matlab.ui.control.Button
        EnableRecord                  matlab.ui.control.CheckBox
        FileNameLabel                 matlab.ui.control.Label
        SaveName                      matlab.ui.control.EditField
        AppendtimedateCheckBox        matlab.ui.control.CheckBox
        SensorInformationButtonGroup  matlab.ui.container.ButtonGroup
        SensorMountingHeightmEditFieldLabel  matlab.ui.control.Label
        MountingHeight                matlab.ui.control.NumericEditField
        SensorElevationTiltdegEditFieldLabel  matlab.ui.control.Label
        ElevationTilt                 matlab.ui.control.NumericEditField
        SensorAzimuthTiltdegEditFieldLabel  matlab.ui.control.Label
        AzimuthTilt                   matlab.ui.control.NumericEditField
        SetupCompleteButton           matlab.ui.control.Button
        VisualizerOptionsButtonGroup  matlab.ui.container.ButtonGroup
        NumLanesLabel                 matlab.ui.control.Label
        NumLanes                      matlab.ui.control.NumericEditField
        LaneWidthmLabel               matlab.ui.control.Label
        LaneWidth                     matlab.ui.control.NumericEditField
        PositionLaneXmLabel           matlab.ui.control.Label
        LaneX                         matlab.ui.control.NumericEditField
        EnableLanes                   matlab.ui.control.CheckBox
        LaneLengthmLabel              matlab.ui.control.Label
        LaneLength                    matlab.ui.control.NumericEditField
        PositionLaneYmLabel           matlab.ui.control.Label
        LaneY                         matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        mode = 1;
        datFile = struct('name', [], 'path', []);
        cfgFile = struct('name', [], 'path', []);
        logFile = struct('name', [], 'path', []);
        offset = struct('height', [], 'az', [], 'rot', []);
        comPort = struct('cfg',1,'data',1,'status',0);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SetupUIFigure.Visible = 'off';
            movegui(app.SetupUIFigure,'center');
            try
                VisualizerModeButtonGroupSelectionChanged(app, []);
                if exist('state.mat')
                    load('state.mat')
                    app.RealTimeButton.Value = REAL_TIME_MODE;
                    VisualizerModeButtonGroupSelectionChanged(app, []);
                    app.EnableRecord.Value = ENABLE_RECORD;
                    app.DatPath.Value = [datFile.path datFile.name];
                    app.CfgPath.Value = [cfgFile.path cfgFile.name];
                    
                    app.SavePath.Value = logFile.path;
                    app.SaveName.Value = logFile.name;
                    app.MountingHeight.Value = offset.height;
                    app.AzimuthTilt.Value = offset.az;
                    app.ElevationTilt.Value = offset.el;
                    app.CfgPort.Value = comPort.cfg;
                    app.DatPort.Value = comPort.data;
                    app.EnableLanes.Value = lanes.enable;
                    app.LaneX.Value = lanes.x;
                    app.LaneY.Value = lanes.y;
                    app.LaneWidth.Value = lanes.w;
                    app.LaneLength.Value = lanes.h;
                    app.NumLanes.Value = lanes.numLanes;
                end
            catch ME
                
            end
            uiwait(app.SetupUIFigure)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SetupUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {644, 644};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {552, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: VisualizerModeButtonGroup
        function VisualizerModeButtonGroupSelectionChanged(app, event)
            if(app.RealTimeButton.Value)
                app.mode = 1;
                app.DatPanel.Visible = 'off';
                app.COMPanel.Visible = 'on';
                app.RecordDataPanel.Visible = 'on';
            else
                app.mode = 2;
                app.DatPanel.Visible = 'on';
                app.COMPanel.Visible = 'off';
                app.RecordDataPanel.Visible = 'off';
                
            end
            
        end

        % Button pushed function: DatButton
        function DatButtonPushed(app, event)
            [app.datFile.name app.datFile.path] = uigetfile('*.txt','Select Log File');
            if(ischar(app.datFile.name))
                app.DatPath.Value = [app.datFile.path app.datFile.name] ;
            end
            figure(app.SetupUIFigure);
        end

        % Button pushed function: CfgButton
        function CfgButtonPushed(app, event)
            [app.cfgFile.name app.cfgFile.path] = uigetfile('*.cfg','Select CFG File');
            if(ischar(app.cfgFile.name))
                app.CfgPath.Value =  [app.cfgFile.path app.cfgFile.name] ;
            end
            figure(app.SetupUIFigure);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.logFile.path = uigetdir('Select Folder to Save');
            if(ischar(app.logFile.path))
                app.SavePath.Value = [app.logFile.path];
            end
            figure(app.SetupUIFigure);
        end

        % Value changed function: CfgPath
        function CfgPathValueChanged(app, event)
            [app.cfgFile.path,name,ext] = fileparts(app.CfgPath.Value);
            app.cfgFile.name = [name ext];
        end

        % Value changed function: DatPath
        function DatPathValueChanged(app, event)
            [app.datFile.path,name,ext] = fileparts(app.DatPath.Value);
            app.datFile.name = [name ext];
        end

        % Value changed function: CfgPort
        function CfgPortValueChanged(app, event)
            app.comPort.cfg = app.CfgPort.Value;
        end

        % Value changed function: DatPort
        function DatPortValueChanged(app, event)
            app.comPort.data = app.DatPort.Value;
        end

        % Button pushed function: TestConnectionButton
        function TestConnectionButtonPushed(app, event)
            hDataPort = initDataPort(app.DatPort.Value);
            hCfgPort = initCfgPort(app.CfgPort.Value);
            
            % send version command
            if(hCfgPort ~= -1 && hDataPort ~=-1)
                if(hDataPort.BytesAvailable)
                    %TODO: remove warning when config reload w/o NRST is enabled
                    uialert(app.SetupUIFigure, 'Device appears to already be running. Will not be able to load a new configuration. To load a new config, press NRST on the EVM and try again.','Need to assert NRST');    
                    app.comPort.status = -1;
                    return;
                else       
                    fprintf(hCfgPort, 'version');
                    pause(0.5); % adding some delay to make sure bytes are received
                    response = '';
                    if(hCfgPort.BytesAvailable)
                        for i=1:10 % version command reports back 10 lines TODO: change if SDK changes response
                            rstr = fgets(hCfgPort);
                            response = append(response, rstr);
                        end
                        uialert(app.SetupUIFigure, response,'Test successful: CFG Port Opened & Data Received','icon','success');
                        app.comPort.status = 1;
                    else
                        uialert(app.SetupUIFigure,'Port opened but no response received. Check port # and SOP mode on EVM','Issue with CFG Port');
                        app.comPort.status = -2;
                        fclose(hDataPort);
                        fclose(hCfgPort);
                    end
                end
            else
                app.comPort.status = -2;
                uialert(app.SetupUIFigure, 'Could not open ports. Check port # and that EVM is powered with correct SOP mode.','Ports not valid');    
            end
        end

        % Button pushed function: SetupCompleteButton
        function SetupCompleteButtonPushed(app, event)

           REAL_TIME_MODE = app.RealTimeButton.Value;
           ENABLE_RECORD = app.EnableRecord.Value;
           [dPath,dName,dExt] = fileparts(app.DatPath.Value);
           datFile.path = [dPath '\'];
           datFile.name = [dName dExt];
           [cPath,cName,cExt] = fileparts(app.CfgPath.Value);
           cfgFile.path = [cPath '\'];
           cfgFile.name = [cName cExt];
           logFile.path = app.SavePath.Value;
           logFile.name = app.SaveName.Value;
           offset.height = app.MountingHeight.Value;
           offset.az = app.AzimuthTilt.Value;
           offset.el = app.ElevationTilt.Value;
           comPort.cfg = app.CfgPort.Value;
           comPort.data = app.DatPort.Value;
           comPort.status = app.comPort.status;
           lanes = struct('enable', app.EnableLanes.Value, 'x', app.LaneX.Value, 'y', app.LaneY.Value,...
               'w', app.LaneWidth.Value, 'h', app.LaneLength.Value, 'numLanes', app.NumLanes.Value);

            assignin('base', 'REAL_TIME_MODE', REAL_TIME_MODE);
            assignin('base', 'ENABLE_RECORD', ENABLE_RECORD);
            assignin('base', 'datFile', datFile);
            assignin('base', 'cfgFile', cfgFile);
            assignin('base', 'logFile', logFile);
            assignin('base', 'comPort', comPort);
            assignin('base', 'offset', offset);
            assignin('base', 'lanes', lanes);
            save('state.mat','REAL_TIME_MODE', 'ENABLE_RECORD','datFile','cfgFile','logFile','offset','comPort','lanes');
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetupUIFigure = uifigure('Visible', 'off');
            app.SetupUIFigure.Name = 'Setup ';
            app.SetupUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SetupUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VisualizerModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.VisualizerModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizerModeButtonGroupSelectionChanged, true);
            app.VisualizerModeButtonGroup.Title = 'Visualizer Mode';
            app.RealTimeButton = uitogglebutton(app.VisualizerModeButtonGroup);
            app.RealTimeButton.Text = 'Real Time';
            app.RealTimeButton.Value = true;
            app.PlayBackButton = uitogglebutton(app.VisualizerModeButtonGroup);
            app.PlayBackButton.Text = 'Play Back';
            app.COMPanel = uipanel(app.VisualizerModeButtonGroup);
            app.COMPanel.Title = 'Set COM port for real time';
            app.CFG_PORTLabel = uilabel(app.COMPanel);
            app.CFG_PORTLabel.Text = 'CFG_PORT';
            app.CfgPort = uieditfield(app.COMPanel, 'numeric');
            app.CfgPort.Limits = [1 99];
            app.CfgPort.ValueChangedFcn = createCallbackFcn(app, @CfgPortValueChanged, true);
            app.CfgPort.Tooltip = {'Appears as User UART or Standard Port in Device Manager'};
            app.CfgPort.Value = 1;
            app.DATA_PORTLabel = uilabel(app.COMPanel);
            app.DATA_PORTLabel.Text = 'DATA_PORT';
            app.DatPort = uieditfield(app.COMPanel, 'numeric');
            app.DatPort.Limits = [1 99];
            app.DatPort.ValueChangedFcn = createCallbackFcn(app, @DatPortValueChanged, true);
            app.DatPort.Tooltip = {'Appears as Data or Enhanced Port in Device Manager'};
            app.DatPort.Value = 1;
            app.TestConnectionButton = uibutton(app.COMPanel, 'push');
            app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
            app.TestConnectionButton.Text = 'Test Connection';
            app.DatPanel = uipanel(app.VisualizerModeButtonGroup);
            app.DatPanel.Title = 'Select data log file for play back';
            app.DatButton = uibutton(app.DatPanel, 'push');
            app.DatButton.ButtonPushedFcn = createCallbackFcn(app, @DatButtonPushed, true);
            app.DatButton.Icon = 'folder_file_icon.png';
            app.DatButton.Text = 'Browse';
            app.DataFileLabel = uilabel(app.DatPanel);
            app.DataFileLabel.Text = 'Data File';
            app.DatPath = uieditfield(app.DatPanel, 'text');
            app.DatPath.ValueChangedFcn = createCallbackFcn(app, @DatPathValueChanged, true);
            app.DatPath.Tooltip = {'Enter full path to file or use Browse button.'};
            app.SelectCFGFilePanel = uipanel(app.LeftPanel);
            app.SelectCFGFilePanel.Title = 'Select CFG File';
            app.CFGFileLabel = uilabel(app.SelectCFGFilePanel);
            app.CFGFileLabel.Text = 'CFG File';
            app.CfgPath = uieditfield(app.SelectCFGFilePanel, 'text');
            app.CfgPath.ValueChangedFcn = createCallbackFcn(app, @CfgPathValueChanged, true);
            app.CfgPath.Tooltip = {'Enter full path to file or use Browse button.'};
            app.CfgButton = uibutton(app.SelectCFGFilePanel, 'push');
            app.CfgButton.ButtonPushedFcn = createCallbackFcn(app, @CfgButtonPushed, true);
            app.CfgButton.Icon = 'folder_file_icon.png';
            app.CfgButton.Text = 'Browse';
            app.RecordDataPanel = uipanel(app.LeftPanel);
            app.RecordDataPanel.Title = 'Record Data';
            app.SaveDirectoryLabel = uilabel(app.RecordDataPanel);
            app.SaveDirectoryLabel.Text = 'Save Directory';
            app.SavePath = uieditfield(app.RecordDataPanel, 'text');
            app.SavePath.Editable = 'off';
            app.SavePath.Tooltip = {'Browse or leave blank for current directory.'};
            app.LogButton = uibutton(app.RecordDataPanel, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Icon = 'foldericon.png';
            app.LogButton.Text = 'Browse';
            app.EnableRecord = uicheckbox(app.RecordDataPanel);
            app.EnableRecord.Text = 'Enable recording. UART stream will be saved to file.';
            app.EnableRecord.Value = true;
            app.FileNameLabel = uilabel(app.RecordDataPanel);
            app.FileNameLabel.Text = 'File Name';
            app.SaveName = uieditfield(app.RecordDataPanel, 'text');
            app.SaveName.Tooltip = {'Enter full path to file or use Browse button.'};
            app.SaveName.Value = 'tm_demo_uart_stream.txt';
            app.AppendtimedateCheckBox = uicheckbox(app.RecordDataPanel);
            app.AppendtimedateCheckBox.Enable = 'off';
            app.AppendtimedateCheckBox.Visible = 'off';
            app.AppendtimedateCheckBox.Text = 'Append time & date';
            app.AppendtimedateCheckBox.Value = true;
            app.SensorInformationButtonGroup = uibuttongroup(app.LeftPanel);
            app.SensorInformationButtonGroup.Title = 'Sensor Information';
            app.SensorMountingHeightmEditFieldLabel = uilabel(app.SensorInformationButtonGroup);
            app.SensorMountingHeightmEditFieldLabel.Text = 'Sensor Mounting Height [m]:';
            app.MountingHeight = uieditfield(app.SensorInformationButtonGroup, 'numeric');
            app.MountingHeight.LowerLimitInclusive = 'off';
            app.MountingHeight.Limits = [0 Inf];
            app.MountingHeight.Tooltip = {'Height of the sensor from the ground'};
            app.MountingHeight.Value = 2;
            app.SensorElevationTiltdegEditFieldLabel = uilabel(app.SensorInformationButtonGroup);
            app.SensorElevationTiltdegEditFieldLabel.Text = 'Sensor Elevation Tilt [deg]:';
            app.ElevationTilt = uieditfield(app.SensorInformationButtonGroup, 'numeric');
            app.ElevationTilt.Limits = [-90 90];
            app.ElevationTilt.Tooltip = {'Rotation about X-axis. + is upwards. - is down tilt. Valid values: -90 to 90'};
            app.SensorAzimuthTiltdegEditFieldLabel = uilabel(app.SensorInformationButtonGroup);
            app.SensorAzimuthTiltdegEditFieldLabel.Text = 'Sensor Azimuth Tilt [deg]:';
            app.AzimuthTilt = uieditfield(app.SensorInformationButtonGroup, 'numeric');
            app.AzimuthTilt.Limits = [-90 90];
            app.AzimuthTilt.Tooltip = {'Rotation about Z-axis.  + is towards positive X-axis. - is towards negative X-axis. Valid values: -90 to 90'};
            app.SetupCompleteButton = uibutton(app.LeftPanel, 'push');
            app.SetupCompleteButton.ButtonPushedFcn = createCallbackFcn(app, @SetupCompleteButtonPushed, true);
            app.SetupCompleteButton.Text = 'Setup Complete';
            app.VisualizerOptionsButtonGroup = uibuttongroup(app.LeftPanel);
            app.VisualizerOptionsButtonGroup.Title = 'Visualizer Options';
            app.NumLanesLabel = uilabel(app.VisualizerOptionsButtonGroup);
            app.NumLanesLabel.Text = 'Num Lanes:';
            app.NumLanes = uieditfield(app.VisualizerOptionsButtonGroup, 'numeric');
            app.NumLanes.Limits = [1 10];
            app.NumLanes.RoundFractionalValues = 'on';
            app.NumLanes.Tooltip = {''; ''};
            app.NumLanes.Value = 3;
            app.LaneWidthmLabel = uilabel(app.VisualizerOptionsButtonGroup);
            app.LaneWidthmLabel.Text = 'Lane Width [m]: ';
            app.LaneWidth = uieditfield(app.VisualizerOptionsButtonGroup, 'numeric');
            app.LaneWidth.LowerLimitInclusive = 'off';
            app.LaneWidth.Limits = [0 Inf];
            app.LaneWidth.Tooltip = {'+ value will replicate lanes in positive X direction'; ' - values will replicate lanes in negative X direction'};
            app.LaneWidth.Value = 3;
            app.PositionLaneXmLabel = uilabel(app.VisualizerOptionsButtonGroup);
            app.PositionLaneXmLabel.Text = 'Position Lane X [m]:';
            app.LaneX = uieditfield(app.VisualizerOptionsButtonGroup, 'numeric');
            app.LaneX.Tooltip = {'X coordinate of the first lane'};
            app.LaneX.Value = 1;
            app.EnableLanes = uicheckbox(app.VisualizerOptionsButtonGroup);
            app.EnableLanes.Text = 'Draw lane markings.';
            app.EnableLanes.Value = true;
            app.LaneLengthmLabel = uilabel(app.VisualizerOptionsButtonGroup);
            app.LaneLengthmLabel.Text = 'Lane Length [m]: ';
            app.LaneLength = uieditfield(app.VisualizerOptionsButtonGroup, 'numeric');
            app.LaneLength.LowerLimitInclusive = 'off';
            app.LaneLength.Limits = [0 Inf];
            app.LaneLength.Tooltip = {'Length of lane along Y-Axis'};
            app.LaneLength.Value = 70;
            app.PositionLaneYmLabel = uilabel(app.VisualizerOptionsButtonGroup);
            app.PositionLaneYmLabel.Text = 'Position Lane Y [m]: ';
            app.LaneY = uieditfield(app.VisualizerOptionsButtonGroup, 'numeric');
            app.LaneY.Limits = [0 Inf];
            app.LaneY.Tooltip = {'Y coordinate of the start of the lanes. (Start of lane as measured from sensor position)'};
            app.LaneY.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.SetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setup_tm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupUIFigure)
        end
    end
end